classdef analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        Panel                      matlab.ui.container.Panel
        DeleteoldmatfilesCheckBox  matlab.ui.control.CheckBox
        selectdatafolderButton     matlab.ui.control.Button
        selectresultsfolderButton  matlab.ui.control.Button
        RunplayeranalysisButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectdatafolderButton
        function selectdatafolderButtonPushed(app, event)
            if ispc
                try
                    dirname = uigetdir('C:\Users\david\googledrive\MATLAB\soccer\pod_data');
                catch
                    dirname = uigetdir('C:\');
                end
            else
                dirname = uigetdir('~');
            end
            assignin('base', 'dname', dirname);
        end

        % Value changed function: DeleteoldmatfilesCheckBox
        function DeleteoldmatfilesCheckBoxValueChanged(app, event)
            value = app.DeleteoldmatfilesCheckBox.Value;
            if value
                %% Option to delete .mat files (for testing)
                answer = questdlg('Delete any existing player .mat files in the current working directory? (for testing player_analysis)', ...
                    'Delete', ...
                    'Delete','Keep','Cancel','Delete');
                % Handle response
                switch answer
                    case 'Delete'
                        delete data_AlexVillanueva.mat data_AntinoLopez.mat
                        delete data_ChristianKoontz.mat data_LeoBurney.mat
                        delete data_RaySerrano.mat data_SotaKitahara.mat
                    case 'Keep'
                        % do nothing
                    case 'Cancel'
                        return
                end
            end
        end

        % Button pushed function: selectresultsfolderButton
        function selectresultsfolderButtonPushed(app, event)
            if ispc
                folder = uigetdir('C:\Users\david\googledrive\MATLAB\soccer\results');
            else
                folder = uigetdir('~');
            end
            assignin('base', 'results_folder', folder);
        end

        % Button pushed function: RunplayeranalysisButton
        function RunplayeranalysisButtonPushed(app, event)
            assignin('base', 'app', true);
            cd('C:\Users\david\googledrive\MATLAB\soccer\player_analysis')
            evalin('base', ('run(''player_analysis2.m'')'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'takeoff'; 'land'; 'airtime'; 'height'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DeleteoldmatfilesCheckBox = uicheckbox(app.Panel);
            app.DeleteoldmatfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeleteoldmatfilesCheckBoxValueChanged, true);
            app.DeleteoldmatfilesCheckBox.Text = 'Delete old .mat files';
            app.selectdatafolderButton = uibutton(app.Panel, 'push');
            app.selectdatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectdatafolderButtonPushed, true);
            app.selectdatafolderButton.Text = 'select data folder';
            app.selectresultsfolderButton = uibutton(app.Panel, 'push');
            app.selectresultsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectresultsfolderButtonPushed, true);
            app.selectresultsfolderButton.Text = 'select results folder';
            app.RunplayeranalysisButton = uibutton(app.Panel, 'push');
            app.RunplayeranalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunplayeranalysisButtonPushed, true);
            app.RunplayeranalysisButton.Text = 'Run player analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end